classdef fmcw_mimo_sar_simulator_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InfoMenu                        matlab.ui.container.Menu
        OpenDocumentationMenu           matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        WISLABWebsiteButton             matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        ImageReconstructionCompleteLamp  matlab.ui.control.Lamp
        ImageReconstructionCompleteLampLabel  matlab.ui.control.Label
        BeatSignalComputedLamp          matlab.ui.control.Lamp
        BeatSignalComputedLampLabel     matlab.ui.control.Label
        TargetUptoDateLamp              matlab.ui.control.Lamp
        TargetUptoDateLampLabel         matlab.ui.control.Label
        SARScenarioUptoDateLamp         matlab.ui.control.Lamp
        SARScenarioUptoDateLampLabel    matlab.ui.control.Label
        AntennaArrayUptoDateLamp        matlab.ui.control.Lamp
        AntennaArrayUptoDateLampLabel   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RadarSetupTab                   matlab.ui.container.Tab
        OptionsPanel                    matlab.ui.container.Panel
        MIMOSwitch                      matlab.ui.control.Switch
        ResetFiguresButton              matlab.ui.control.Button
        SaveLoadMIMOArrayPanel          matlab.ui.container.Panel
        ArrayLoadNameEditField          matlab.ui.control.EditField
        ArraySaveNameEditField          matlab.ui.control.EditField
        ArrayLoadButton                 matlab.ui.control.Button
        ArraySaveButton                 matlab.ui.control.Button
        SaveLoadFMCWChripParametersPanel  matlab.ui.container.Panel
        FMCWLoadNameEditField           matlab.ui.control.EditField
        FMCWSaveNameEditField           matlab.ui.control.EditField
        FMCWLoadButton                  matlab.ui.control.Button
        FMCWSaveButton                  matlab.ui.control.Button
        SARSetupPanel                   matlab.ui.container.Panel
        XSizemEditField                 matlab.ui.control.NumericEditField
        XSizemEditFieldLabel            matlab.ui.control.Label
        YSizemEditField                 matlab.ui.control.NumericEditField
        YSizemEditFieldLabel            matlab.ui.control.Label
        YStepSwitch                     matlab.ui.control.Switch
        XStepSwitch                     matlab.ui.control.Switch
        ThetaSizedegEditField           matlab.ui.control.NumericEditField
        SizedegEditFieldLabel           matlab.ui.control.Label
        NumThetaStepsEditField          matlab.ui.control.NumericEditField
        NumStepsEditFieldLabel          matlab.ui.control.Label
        YStepSizeEditField              matlab.ui.control.NumericEditField
        YStepSizeLabel                  matlab.ui.control.Label
        NumXStepsEditField              matlab.ui.control.NumericEditField
        NumXStepsEditFieldLabel         matlab.ui.control.Label
        NumYStepsEditField              matlab.ui.control.NumericEditField
        NumYStepsEditFieldLabel         matlab.ui.control.Label
        XStepSizeEditField              matlab.ui.control.NumericEditField
        XStepSizeLabel                  matlab.ui.control.Label
        SARMethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ReceiverElementsPanel           matlab.ui.container.Panel
        ReceiverZmEditField             matlab.ui.control.NumericEditField
        ReceiverZmEditFieldLabel        matlab.ui.control.Label
        RemoveRxButton                  matlab.ui.control.Button
        AddRxButton                     matlab.ui.control.Button
        RxTable                         matlab.ui.control.Table
        TransmitterElementsPanel        matlab.ui.container.Panel
        TransmitterZmEditField          matlab.ui.control.NumericEditField
        TransmitterZmEditFieldLabel     matlab.ui.control.Label
        RemoveTxButton                  matlab.ui.control.Button
        AddTxButton                     matlab.ui.control.Button
        TxTable                         matlab.ui.control.Table
        FMCWChirpParametersPanel        matlab.ui.container.Panel
        UsefulBandwidthGHzEditField     matlab.ui.control.NumericEditField
        UsefulBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        RangeResolutionmmEditField      matlab.ui.control.NumericEditField
        RangeResolutionmmEditFieldLabel  matlab.ui.control.Label
        MaximumRangemEditField          matlab.ui.control.NumericEditField
        MaximumRangemEditFieldLabel     matlab.ui.control.Label
        WavelengthmmEditField           matlab.ui.control.NumericEditField
        WavelengthmmEditFieldLabel      matlab.ui.control.Label
        TotalBandwidthGHzEditField      matlab.ui.control.NumericEditField
        TotalBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        MaximumBandwidthGHzEditField    matlab.ui.control.NumericEditField
        MaximumBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        CenterFrequencyGHzEditField     matlab.ui.control.NumericEditField
        CenterFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        SamplingFrequencykspsEditField  matlab.ui.control.NumericEditField
        SamplingFrequencykspsEditFieldLabel  matlab.ui.control.Label
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        ADCStartTimeusEditField         matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel    matlab.ui.control.Label
        TXStartTimeusEditField          matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel     matlab.ui.control.Label
        IdleTimeusEditField             matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel        matlab.ui.control.Label
        FreqSlopeMHzusEditField         matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel    matlab.ui.control.Label
        StartingFrequencyGHzEditField   matlab.ui.control.NumericEditField
        StartingFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        TargetSetupTab                  matlab.ui.container.Tab
        SimulateBeatSignalPanel         matlab.ui.container.Panel
        TotalNumTargetsEditField        matlab.ui.control.NumericEditField
        TotalNumTargetsEditFieldLabel   matlab.ui.control.Label
        ComputeBeatSignalButton         matlab.ui.control.Button
        SaveLoadTargetPanel             matlab.ui.container.Panel
        TargetLoadNameEditField         matlab.ui.control.EditField
        TargetSaveNameEditField         matlab.ui.control.EditField
        TargetLoadButton                matlab.ui.control.Button
        TargetSaveButton                matlab.ui.control.Button
        ImportSTLFilePanel              matlab.ui.container.Panel
        LoadSTLLamp                     matlab.ui.control.Lamp
        ZCropmEditField                 matlab.ui.control.NumericEditField
        ZCropmEditFieldLabel            matlab.ui.control.Label
        STLLoadButton                   matlab.ui.control.Button
        NumTargetsEditField_2           matlab.ui.control.NumericEditField
        NumTargetsEditField_2Label      matlab.ui.control.Label
        STLFileNameEditField            matlab.ui.control.EditField
        STLFileNameEditFieldLabel       matlab.ui.control.Label
        ReflectivityEditField_2         matlab.ui.control.NumericEditField
        ReflectivityEditField_2Label    matlab.ui.control.Label
        ZOffsetmEditField_2             matlab.ui.control.NumericEditField
        ZOffsetmEditField_2Label        matlab.ui.control.Label
        DownsampleFactorEditField_2     matlab.ui.control.NumericEditField
        DownsampleFactorEditField_2Label  matlab.ui.control.Label
        YOffsetmEditField_2             matlab.ui.control.NumericEditField
        YOffsetmEditField_2Label        matlab.ui.control.Label
        XOffsetmEditField_2             matlab.ui.control.NumericEditField
        XOffsetmEditField_2Label        matlab.ui.control.Label
        ImportPNGImagePanel             matlab.ui.container.Panel
        NumTargetsEditField             matlab.ui.control.NumericEditField
        NumTargetsEditFieldLabel        matlab.ui.control.Label
        PNGFileNameEditField            matlab.ui.control.EditField
        PNGFileNameEditFieldLabel       matlab.ui.control.Label
        ReflectivityEditField           matlab.ui.control.NumericEditField
        ReflectivityEditFieldLabel      matlab.ui.control.Label
        ZOffsetmEditField               matlab.ui.control.NumericEditField
        ZOffsetmEditFieldLabel          matlab.ui.control.Label
        DownsampleFactorEditField       matlab.ui.control.NumericEditField
        DownsampleFactorEditFieldLabel  matlab.ui.control.Label
        YOffsetmEditField               matlab.ui.control.NumericEditField
        YOffsetmEditFieldLabel          matlab.ui.control.Label
        XOffsetmEditField               matlab.ui.control.NumericEditField
        XOffsetmEditFieldLabel          matlab.ui.control.Label
        YPixelSizemEditField            matlab.ui.control.NumericEditField
        YPixelSizemEditFieldLabel       matlab.ui.control.Label
        XPixelSizemEditField            matlab.ui.control.NumericEditField
        XPixelSizemEditFieldLabel       matlab.ui.control.Label
        GenerateRandomPointsPanel       matlab.ui.container.Panel
        ReflectivityMaxEditField        matlab.ui.control.NumericEditField
        ReflectivityMaxEditFieldLabel   matlab.ui.control.Label
        ReflectivityMinEditField        matlab.ui.control.NumericEditField
        ReflectivityMinEditFieldLabel   matlab.ui.control.Label
        ZMaxmEditField                  matlab.ui.control.NumericEditField
        ZMaxmEditFieldLabel             matlab.ui.control.Label
        ZMinmEditField                  matlab.ui.control.NumericEditField
        ZMinmEditFieldLabel             matlab.ui.control.Label
        YMaxmEditField                  matlab.ui.control.NumericEditField
        YMaxmEditFieldLabel             matlab.ui.control.Label
        YMinmEditField                  matlab.ui.control.NumericEditField
        YMinmEditFieldLabel             matlab.ui.control.Label
        XMaxmEditField                  matlab.ui.control.NumericEditField
        XMaxmLabel                      matlab.ui.control.Label
        XMinmEditField                  matlab.ui.control.NumericEditField
        XMinmEditFieldLabel             matlab.ui.control.Label
        NumTargetsEditField_3           matlab.ui.control.NumericEditField
        NumTargetsEditField_3Label      matlab.ui.control.Label
        GenerateRandomPointsButton      matlab.ui.control.Button
        TableofTargetsPanel             matlab.ui.container.Panel
        RemoveTargetButton              matlab.ui.control.Button
        AddTargetButton                 matlab.ui.control.Button
        TargetTable                     matlab.ui.control.Table
        OptionsPanel_2                  matlab.ui.container.Panel
        PathLossCheckBox                matlab.ui.control.CheckBox
        UseSTLFileCheckBox              matlab.ui.control.CheckBox
        UsePNGFileCheckBox              matlab.ui.control.CheckBox
        UseTableofTargetsCheckBox       matlab.ui.control.CheckBox
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        ReconstructImageTab             matlab.ui.container.Tab
        MutlistatictoMonostaticConversionPanel  matlab.ui.container.Panel
        ZReferencemEditField            matlab.ui.control.NumericEditField
        ZReferencemLabel                matlab.ui.control.Label
        isMult2MonoCheckBox             matlab.ui.control.CheckBox
        DisplayOptionsPanel             matlab.ui.container.Panel
        FontSizeEditField               matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel          matlab.ui.control.Label
        MindBEditField                  matlab.ui.control.NumericEditField
        MindBEditFieldLabel             matlab.ui.control.Label
        DisplayImageButton              matlab.ui.control.Button
        OpeninVolumeViewerButton        matlab.ui.control.Button
        ImageReconstructionOptionsPanel  matlab.ui.container.Panel
        UseGPUCheckBox_2                matlab.ui.control.CheckBox
        ReconstructTargetButton         matlab.ui.control.Button
        NumZVoxelsEditField             matlab.ui.control.NumericEditField
        NumZVoxelsEditFieldLabel        matlab.ui.control.Label
        NumYVoxelsEditField             matlab.ui.control.NumericEditField
        NumYVoxelsEditFieldLabel        matlab.ui.control.Label
        NumXVoxelsEditField             matlab.ui.control.NumericEditField
        NumXVoxelsEditFieldLabel        matlab.ui.control.Label
        ZMaxmEditField_im               matlab.ui.control.NumericEditField
        ZMaxmEditField_2Label           matlab.ui.control.Label
        ZMinmEditField_im               matlab.ui.control.NumericEditField
        ZMinmEditField_2Label           matlab.ui.control.Label
        YMaxmEditField_im               matlab.ui.control.NumericEditField
        YMaxmEditField_2Label           matlab.ui.control.Label
        YMinmEditField_im               matlab.ui.control.NumericEditField
        YMinmEditField_2Label           matlab.ui.control.Label
        XMaxmEditField_im               matlab.ui.control.NumericEditField
        XMaxmEditField_2Label           matlab.ui.control.Label
        XMinmEditField_im               matlab.ui.control.NumericEditField
        XMinmEditField_2Label           matlab.ui.control.Label
        ReconstructionAlgorithmDropDown  matlab.ui.control.DropDown
        NumberofZFFTPointsEditField     matlab.ui.control.NumericEditField
        NumberofZFFTPointsEditField_2Label  matlab.ui.control.Label
        NumberofYFFTPointsEditField     matlab.ui.control.NumericEditField
        NumberofYFFTPointsEditField_2Label_2  matlab.ui.control.Label
        NumberofXFFTPointsEditField     matlab.ui.control.NumericEditField
        NumberofXFFTPointsEditField_2Label  matlab.ui.control.Label
        ImageReconstructionTabGroup     matlab.ui.container.TabGroup
        Uniform2DSAR3DRMATab            matlab.ui.container.Tab
        ZMaxmEditField_im_2             matlab.ui.control.NumericEditField
        ZMaxmEditField_2Label_2         matlab.ui.control.Label
        ZMinmEditField_im_2             matlab.ui.control.NumericEditField
        ZMinmEditField_2Label_2         matlab.ui.control.Label
        YMaxmEditField_im_2             matlab.ui.control.NumericEditField
        YMaxmEditField_2Label_2         matlab.ui.control.Label
        YMinmEditField_im_2             matlab.ui.control.NumericEditField
        YMinmEditField_2Label_2         matlab.ui.control.Label
        XMaxmEditField_im_2             matlab.ui.control.NumericEditField
        XMaxmEditField_2Label_2         matlab.ui.control.Label
        XMinmEditField_im_2             matlab.ui.control.NumericEditField
        XMinmEditField_2Label_2         matlab.ui.control.Label
        Uniform1DSAR2DRMATab            matlab.ui.container.Tab
        ZMaxmEditField_im_3             matlab.ui.control.NumericEditField
        ZMaxmEditField_2Label_3         matlab.ui.control.Label
        ZMinmEditField_im_3             matlab.ui.control.NumericEditField
        ZMinmEditField_2Label_3         matlab.ui.control.Label
        YMaxmEditField_im_5             matlab.ui.control.NumericEditField
        YMaxmEditField_2Label_5         matlab.ui.control.Label
        YMinmEditField_im_5             matlab.ui.control.NumericEditField
        YMinmEditField_2Label_5         matlab.ui.control.Label
        Uniform2DSAR2DFFTTab            matlab.ui.container.Tab
        YMaxmEditField_im_3             matlab.ui.control.NumericEditField
        YMaxmEditField_2Label_3         matlab.ui.control.Label
        YMinmEditField_im_3             matlab.ui.control.NumericEditField
        YMinmEditField_2Label_3         matlab.ui.control.Label
        XMaxmEditField_im_3             matlab.ui.control.NumericEditField
        XMaxmEditField_2Label_3         matlab.ui.control.Label
        XMinmEditField_im_3             matlab.ui.control.NumericEditField
        XMinmEditField_2Label_3         matlab.ui.control.Label
        ZSliceEditField_2               matlab.ui.control.NumericEditField
        ZSlicemLabel_2                  matlab.ui.control.Label
        Uniform1DSAR1DFFTTab            matlab.ui.container.Tab
        YMaxmEditField_im_4             matlab.ui.control.NumericEditField
        YMaxmEditField_2Label_4         matlab.ui.control.Label
        YMinmEditField_im_4             matlab.ui.control.NumericEditField
        YMinmEditField_2Label_4         matlab.ui.control.Label
        ZSliceEditField_3               matlab.ui.control.NumericEditField
        ZSlicemLabel_3                  matlab.ui.control.Label
        DSAR2DBPATab                    matlab.ui.container.Tab
        ZSliceEditField                 matlab.ui.control.NumericEditField
        ZSlicemLabel                    matlab.ui.control.Label
        Uniform2DCSAR3DPFATab           matlab.ui.container.Tab
        ThetaUpsampleFactorEditField    matlab.ui.control.NumericEditField
        UpsampleFactorLabel             matlab.ui.control.Label
        ZMaxmEditField_im_4             matlab.ui.control.NumericEditField
        ZMaxmEditField_2Label_4         matlab.ui.control.Label
        ZMinmEditField_im_4             matlab.ui.control.NumericEditField
        ZMinmEditField_2Label_4         matlab.ui.control.Label
        YMaxmEditField_im_6             matlab.ui.control.NumericEditField
        YMaxmEditField_2Label_6         matlab.ui.control.Label
        YMinmEditField_im_6             matlab.ui.control.NumericEditField
        YMinmEditField_2Label_6         matlab.ui.control.Label
        XMaxmEditField_im_4             matlab.ui.control.NumericEditField
        XMaxmEditField_2Label_4         matlab.ui.control.Label
        XMinmEditField_im_4             matlab.ui.control.NumericEditField
        XMinmEditField_2Label_4         matlab.ui.control.Label
        Uniform1DCSAR2DPFATab           matlab.ui.container.Tab
        ThetaUpsampleFactorEditField_2  matlab.ui.control.NumericEditField
        UpsampleFactorLabel_2           matlab.ui.control.Label
        ZMaxmEditField_im_5             matlab.ui.control.NumericEditField
        ZMaxmEditField_2Label_5         matlab.ui.control.Label
        ZMinmEditField_im_5             matlab.ui.control.NumericEditField
        ZMinmEditField_2Label_5         matlab.ui.control.Label
        XMaxmEditField_im_5             matlab.ui.control.NumericEditField
        XMaxmEditField_2Label_5         matlab.ui.control.Label
        XMinmEditField_im_5             matlab.ui.control.NumericEditField
        XMinmEditField_2Label_5         matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        AuthorJosiahWSmithAdvisorMuratTorlakLabel_6  matlab.ui.control.Label
        AuthorJosiahWSmithAdvisorMuratTorlakLabel_5  matlab.ui.control.Label
        AuthorJosiahWSmithAdvisorMuratTorlakLabel_4  matlab.ui.control.Label
        AuthorJosiahWSmithAdvisorMuratTorlakLabel_3  matlab.ui.control.Label
        AuthorJosiahWSmithAdvisorMuratTorlakLabel_2  matlab.ui.control.Label
        AuthorJosiahWSmithAdvisorMuratTorlakLabel  matlab.ui.control.Label
        FMCWMIMOSARSimulatorLabel       matlab.ui.control.Label
    end

    % Copyright (C) 2021 Josiah W. Smith
    %
    % This program is free software: you can redistribute it and/or modify
    % it under the terms of the GNU General Public License as published by
    % the Free Software Foundation, either version 3 of the License, or
    % (at your option) any later version.
    %
    % This program is distributed in the hope that it will be useful,
    % but WITHOUT ANY WARRANTY; without even the implied warranty of
    % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    % GNU General Public License for more details.

    properties (Access = public)
        fmcw
        ant
        sar
        target
        im
    end

    methods (Access = private)

        function closeFigures(app)
            try
                app.ant.closeFigures();
                app.sar.closeFigures();
                app.target.closeFigures();
                app.im.closeFigures();
            catch
            end
        end

        function updateAll(app)
            app.AntennaArrayUptoDateLamp.Color = "red";
            app.SARScenarioUptoDateLamp.Color = "red";
            app.TargetUptoDateLamp.Color = "red";
            app.BeatSignalComputedLamp.Color = "red";
            app.ImageReconstructionCompleteLamp.Color = "red";

            app.ant = app.ant.update(app);
            app.sar = app.sar.update(app);
            app.target = app.target.update(app);

            app.AntennaArrayUptoDateLamp.Color = "green";
            app.SARScenarioUptoDateLamp.Color = "green";
            app.TargetUptoDateLamp.Color = "green";

            app.im = app.im.update(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            splitPath = regexp(cd,filesep,'split');
            if string(splitPath{end}) ~= "application"
                try
                    cd(fileparts(which('fmcw_mimo_sar_simulator_gui.mlapp')));
                catch                    
                end
            end
            splitPath = regexp(cd,filesep,'split');
            while string(splitPath{end}) ~= "application"
                selection = uiconfirm(app.UIFigure,'Would you liked to choose the correct "applications" folder?','Incorrect opening folder',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Cound not find correct folder!");
                    UIFigureCloseRequest(app, 0);
                    return;
                end

                drawnow
                figure(app.UIFigure)
                directoryname = uigetdir("","Select ""application"" Path");
                cd(directoryname);
                splitPath = regexp(cd,filesep,'split');
            end
            addpath(genpath("./"));
            set(0,'DefaultFigureWindowStyle','docked')

            % Create the Tx Table
            app.TxTable.Data = table;
            app.TxTable.ColumnEditable = true;

            % Create the Rx Table
            app.RxTable.Data = table;
            app.RxTable.ColumnEditable = true;

            % Create the Target Table
            app.TargetTable.Data = table;
            app.TargetTable.ColumnEditable = true;
            app.TargetTable.Data(end+1,:) = {0,0,0.1,1};

            % Create the FMCW Chirp Parameters
            app.fmcw = fmcwChirpParameters_app(app);

            % Get the AWR1243 default antenna array
            if ~exist(app.ArrayLoadNameEditField.Value + ".mat",'file')
                uiconfirm(app.UIFigure,"No file called " + app.ArrayLoadNameEditField.Value + ".mat to load",'Cannot Load',...
                    "Options",{'OK'},'Icon','warning');
                warning("Antenna array not loaded!");
                return;
            end

            loadPathFull = "./saved/antennaArrays/" + app.ArrayLoadNameEditField.Value + ".mat";
            load(loadPathFull,"savedant");

            app.TxTable.Data = savedant.TxTable.Data;
            app.RxTable.Data = savedant.RxTable.Data;

            % Create the Antenna Array
            app.ant = antennaArray_app(app);

            % Create the SAR Scenario
            app.sar = sarScenario_app(app);

            % Create the Target
            app.target = sarTarget_app(app);

            % Create the Image
            app.im = sarImage_app(app);

            % Update to the Current Tab
            TabGroupSelectionChanged(app,1);
            ReconstructionAlgorithmDropDownValueChanged(app, 1);

            % Update All
            updateAll(app);
        end

        % Value changed function: StartingFrequencyGHzEditField
        function StartingFrequencyGHzEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: FreqSlopeMHzusEditField
        function FreqSlopeMHzusEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: IdleTimeusEditField
        function IdleTimeusEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: TXStartTimeusEditField
        function TXStartTimeusEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: ADCStartTimeusEditField
        function ADCStartTimeusEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: SamplingFrequencykspsEditField
        function SamplingFrequencykspsEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: RampEndTimeusEditField
        function RampEndTimeusEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Value changed function: CenterFrequencyGHzEditField
        function CenterFrequencyGHzEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
            updateAll(app);
        end

        % Value changed function: MaximumBandwidthGHzEditField
        function MaximumBandwidthGHzEditFieldValueChanged(app, event)
            app.fmcw = app.fmcw.update(app);
        end

        % Button pushed function: AddTxButton
        function AddTxButtonPushed(app, event)
            app.TxTable.Data(end+1,:) = {0,0,0,0,false};
            updateAll(app);
        end

        % Button pushed function: RemoveTxButton
        function RemoveTxButtonPushed(app, event)
            app.TxTable.Data = app.TxTable.Data(1:end-1,:);
            updateAll(app);
        end

        % Button pushed function: AddRxButton
        function AddRxButtonPushed(app, event)
            app.RxTable.Data(end+1,:) = {0,0,0,0,false};
            updateAll(app);
        end

        % Button pushed function: RemoveRxButton
        function RemoveRxButtonPushed(app, event)
            app.RxTable.Data = app.RxTable.Data(1:end-1,:);
            updateAll(app);
        end

        % Button pushed function: FMCWSaveButton
        function FMCWSaveButtonPushed(app, event)
            app.fmcw.saveChirpParameters(app);
        end

        % Button pushed function: FMCWLoadButton
        function FMCWLoadButtonPushed(app, event)
            app.fmcw = app.fmcw.loadChirpParameters(app);
            updateAll(app);
        end

        % Cell edit callback: TxTable
        function TxTableCellEdit(app, event)
            updateAll(app);
        end

        % Cell edit callback: RxTable
        function RxTableCellEdit(app, event)
            updateAll(app);
        end

        % Button pushed function: ArraySaveButton
        function ArraySaveButtonPushed(app, event)
            app.ant.saveAntennaArray(app);
        end

        % Button pushed function: ArrayLoadButton
        function ArrayLoadButtonPushed(app, event)
            app.ant = app.ant.loadAntennaArray(app);
            updateAll(app);
        end

        % Value changed function: SARMethodDropDown
        function SARMethodDropDownValueChanged(app, event)
            switch app.SARMethodDropDown.Value
                case "Linear"
                    app.NumXStepsEditField.Value = 1;
                    app.NumYStepsEditField.Value = 25;
                    app.NumThetaStepsEditField.Value = 1;
                    app.TransmitterZmEditField.Value = 0;
                    app.ReceiverZmEditField.Value = 0;
                    app.ZMinmEditField_im.Value = 0;
                    app.ZMaxmEditField_im.Value = 0.2;
                case "Rectilinear"
                    app.NumXStepsEditField.Value = 200;
                    app.NumYStepsEditField.Value = 25;
                    app.NumThetaStepsEditField.Value = 1;
                    app.TransmitterZmEditField.Value = 0;
                    app.ReceiverZmEditField.Value = 0;
                    app.ZMinmEditField_im.Value = 0;
                    app.ZMaxmEditField_im.Value = 0.2;
                case "Circular"
                    app.NumXStepsEditField.Value = 1;
                    app.NumYStepsEditField.Value = 1;
                    app.NumThetaStepsEditField.Value = 200;
                    app.TransmitterZmEditField.Value = 0.1;
                    app.ReceiverZmEditField.Value = 0.1;
                    app.ZMinmEditField_im.Value = -0.05;
                    app.ZMaxmEditField_im.Value = 0.05;
                case "Cylindrical"
                    app.NumXStepsEditField.Value = 1;
                    app.NumYStepsEditField.Value = 25;
                    app.NumThetaStepsEditField.Value = 200;
                    app.TransmitterZmEditField.Value = 0.1;
                    app.ReceiverZmEditField.Value = 0.1;
                    app.ZMinmEditField_im.Value = -0.05;
                    app.ZMaxmEditField_im.Value = 0.05;
            end
            app.ReconstructionAlgorithmDropDown.Value = "-";
            updateAll(app);
        end

        % Value changed function: XStepSizeEditField
        function XStepSizeEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: NumYStepsEditField
        function NumYStepsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: NumXStepsEditField
        function NumXStepsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: YStepSizeEditField
        function YStepSizeEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: NumThetaStepsEditField
        function NumThetaStepsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: ThetaSizedegEditField
        function ThetaSizedegEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: XStepSwitch
        function XStepSwitchValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: YStepSwitch
        function YStepSwitchValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: TransmitterZmEditField
        function TransmitterZmEditFieldValueChanged(app, event)
            app.ReceiverZmEditField.Value = app.TransmitterZmEditField.Value;
            updateAll(app);
        end

        % Button pushed function: ComputeBeatSignalButton
        function ComputeBeatSignalButtonPushed(app, event)
            app.BeatSignalComputedLamp.Color = "yellow";
            app.target = app.target.computeTarget(app);
            if app.target.sarData ~= 0
                app.BeatSignalComputedLamp.Color = "green";
            else
                app.BeatSignalComputedLamp.Color = "red";
            end
        end

        % Button pushed function: ResetFiguresButton
        function ResetFiguresButtonPushed(app, event)
            app.ant = app.ant.initializeFigures(app);
            app.sar = app.sar.initializeFigures(app);
            app.target = app.target.initializeFigures();
            app.im = app.im.initializeFigures();
            updateAll(app);
            TabGroupSelectionChanged(app, 1);
        end

        % Button pushed function: RemoveTargetButton
        function RemoveTargetButtonPushed(app, event)
            app.TargetTable.Data = app.TargetTable.Data(1:end-1,:);
            if app.UseTableofTargetsCheckBox.Value
                app.target = app.target.update(app);
            end
        end

        % Button pushed function: AddTargetButton
        function AddTargetButtonPushed(app, event)
            switch app.SARMethodDropDown.Value
                case "Linear"
                    app.TargetTable.Data(end+1,:) = {0,0,0.1,1};
                case "Rectilinear"
                    app.TargetTable.Data(end+1,:) = {0,0,0.1,1};
                case "Circular"
                    app.TargetTable.Data(end+1,:) = {0,0,0,1};
                case "Cylindrical"
                    app.TargetTable.Data(end+1,:) = {0,0,0,1};
            end
            if app.UseTableofTargetsCheckBox.Value
                app.target = app.target.update(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closeFigures(app);
            delete(app);
        end

        % Cell edit callback: TargetTable
        function TargetTableCellEdit(app, event)
            if app.UseTableofTargetsCheckBox.Value
                app.target = app.target.update(app);
            end
        end

        % Value changed function: MIMOSwitch
        function MIMOSwitchValueChanged(app, event)
            if app.MIMOSwitch.Value == "Use MIMO Array"
                app.isMult2MonoCheckBox.Value = true;
                app.isMult2MonoCheckBox.Enable = true;
            else
                app.isMult2MonoCheckBox.Value = false;
                app.isMult2MonoCheckBox.Enable = false;
            end
            updateAll(app);
        end

        % Value changed function: UseGPUCheckBox
        function UseGPUCheckBoxValueChanged(app, event)
            app.target = app.target.verifyGPU(app);
        end

        % Value changed function: UseTableofTargetsCheckBox
        function UseTableofTargetsCheckBoxValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: UsePNGFileCheckBox
        function UsePNGFileCheckBoxValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: UseSTLFileCheckBox
        function UseSTLFileCheckBoxValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Button pushed function: ReconstructTargetButton
        function ReconstructTargetButtonPushed(app, event)
            app.ImageReconstructionCompleteLamp.Color = "yellow";
            app.im = app.im.computeImage(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            try
                switch string(app.TabGroup.SelectedTab.Title)
                    case "Radar Setup"
                        figure(app.ant.fig.f);
                    case "Target Setup"
                        figure(app.target.fig.f);
                    case "Reconstruct Image"
                        figure(app.im.fig.f);
                end
                drawnow
                figure(app.UIFigure)
            catch
                app.ant = app.ant.initializeFigures(app);
                app.sar = app.sar.initializeFigures(app);
                app.target = app.target.initializeFigures();
                app.im = app.im.initializeFigures();
                updateAll(app);
                switch string(app.TabGroup.SelectedTab.Title)
                    case "Radar Setup"
                        figure(app.ant.fig.f);
                    case "Target Setup"
                        figure(app.target.fig.f);
                    case "Reconstruct Image"
                        figure(app.im.fig.f);
                end
                drawnow
                figure(app.UIFigure)
            end
        end

        % Value changed function: XPixelSizemEditField
        function XPixelSizemEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: YPixelSizemEditField
        function YPixelSizemEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: XOffsetmEditField
        function XOffsetmEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: YOffsetmEditField
        function YOffsetmEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: DownsampleFactorEditField
        function DownsampleFactorEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: ZOffsetmEditField
        function ZOffsetmEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: ReflectivityEditField
        function ReflectivityEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: PNGFileNameEditField
        function PNGFileNameEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Button pushed function: TargetSaveButton
        function TargetSaveButtonPushed(app, event)
            app.target.saveTarget(app);
        end

        % Button pushed function: TargetLoadButton
        function TargetLoadButtonPushed(app, event)
            app.target = app.target.loadTarget(app);
            app.target = app.target.update(app);
        end

        % Button pushed function: STLLoadButton
        function STLLoadButtonPushed(app, event)
            app.target = app.target.loadSTL(app);
        end

        % Value changed function: ZCropmEditField
        function ZCropmEditFieldValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: XOffsetmEditField_2
        function XOffsetmEditField_2ValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: YOffsetmEditField_2
        function YOffsetmEditField_2ValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: DownsampleFactorEditField_2
        function DownsampleFactorEditField_2ValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: ZOffsetmEditField_2
        function ZOffsetmEditField_2ValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Value changed function: ReflectivityEditField_2
        function ReflectivityEditField_2ValueChanged(app, event)
            app.target = app.target.update(app);
        end

        % Button pushed function: GenerateRandomPointsButton
        function GenerateRandomPointsButtonPushed(app, event)
            app.target = app.target.getRandomTarget(app);
            app.target = app.target.update(app);
        end

        % Value changed function: ReconstructionAlgorithmDropDown
        function ReconstructionAlgorithmDropDownValueChanged(app, event)
            for indTab = 1:length(app.ImageReconstructionTabGroup.Children)
                if app.ReconstructionAlgorithmDropDown.Value == string(app.ImageReconstructionTabGroup.Children(indTab).Title)
                    app.ImageReconstructionTabGroup.SelectedTab = app.ImageReconstructionTabGroup.Children(indTab);
                    break;
                end
            end
            app.im = app.im.update(app);
        end

        % Button pushed function: OpeninVolumeViewerButton
        function OpeninVolumeViewerButtonPushed(app, event)
            app.im.openInVolumeViewer();
        end

        % Value changed function: NumberofXFFTPointsEditField
        function NumberofXFFTPointsEditFieldValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: NumberofYFFTPointsEditField
        function NumberofYFFTPointsEditFieldValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: NumberofZFFTPointsEditField
        function NumberofZFFTPointsEditFieldValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: XMinmEditField_im
        function XMinmEditField_imValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: XMaxmEditField_im
        function XMaxmEditField_imValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: YMinmEditField_im
        function YMinmEditField_imValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: YMaxmEditField_im
        function YMaxmEditField_imValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: ZMinmEditField_im
        function ZMinmEditField_imValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: ZMaxmEditField_im
        function ZMaxmEditField_imValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: NumXVoxelsEditField
        function NumXVoxelsEditFieldValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: NumYVoxelsEditField
        function NumYVoxelsEditFieldValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Value changed function: NumZVoxelsEditField
        function NumZVoxelsEditFieldValueChanged(app, event)
            app.im = app.im.update(app);
        end

        % Button pushed function: DisplayImageButton
        function DisplayImageButtonPushed(app, event)
            app.im.displayImage(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.AboutTab;
        end

        % Menu selected function: OpenDocumentationMenu
        function OpenDocumentationMenuSelected(app, event)
            web("sarSimulatorHome.html");
        end

        % Button pushed function: WISLABWebsiteButton
        function WISLABWebsiteButtonPushed(app, event)
            web("utdallas.edu/~wislab");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.OpenDocumentationMenu = uimenu(app.InfoMenu);
            app.OpenDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDocumentationMenuSelected, true);
            app.OpenDocumentationMenu.Text = 'Open Documentation';
            app.AboutMenu = uimenu(app.InfoMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.FMCWMIMOSARSimulatorLabel = uilabel(app.UIFigure);
            app.FMCWMIMOSARSimulatorLabel.Text = 'FMCW MIMO-SAR Simulator';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RadarSetupTab = uitab(app.TabGroup);
            app.RadarSetupTab.Title = 'Radar Setup';
            app.FMCWChirpParametersPanel = uipanel(app.RadarSetupTab);
            app.FMCWChirpParametersPanel.Title = 'FMCW Chirp Parameters';
            app.StartingFrequencyGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.StartingFrequencyGHzEditFieldLabel.Text = 'Starting Frequency (GHz)';
            app.StartingFrequencyGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.StartingFrequencyGHzEditField.Limits = [0 Inf];
            app.StartingFrequencyGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartingFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @StartingFrequencyGHzEditFieldValueChanged, true);
            app.StartingFrequencyGHzEditField.Value = 300;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.FreqSlopeMHzusEditField.Limits = [0 Inf];
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.ValueChangedFcn = createCallbackFcn(app, @FreqSlopeMHzusEditFieldValueChanged, true);
            app.FreqSlopeMHzusEditField.Value = 200;
            app.IdleTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.IdleTimeusEditField.Limits = [0 Inf];
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @IdleTimeusEditFieldValueChanged, true);
            app.TXStartTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.TXStartTimeusEditField.Limits = [0 Inf];
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.TXStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @TXStartTimeusEditFieldValueChanged, true);
            app.ADCStartTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.ADCStartTimeusEditField.Limits = [0 Inf];
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @ADCStartTimeusEditFieldValueChanged, true);
            app.ADCSamplesEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 50;
            app.SamplingFrequencykspsEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.SamplingFrequencykspsEditFieldLabel.Text = 'Sampling Frequency (ksps)';
            app.SamplingFrequencykspsEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.SamplingFrequencykspsEditField.Limits = [1 Inf];
            app.SamplingFrequencykspsEditField.RoundFractionalValues = 'on';
            app.SamplingFrequencykspsEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencykspsEditFieldValueChanged, true);
            app.SamplingFrequencykspsEditField.Value = 1000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.RampEndTimeusEditField.Limits = [0 Inf];
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @RampEndTimeusEditFieldValueChanged, true);
            app.RampEndTimeusEditField.Value = 50.1;
            app.CenterFrequencyGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.CenterFrequencyGHzEditFieldLabel.Text = 'Center Frequency (GHz)';
            app.CenterFrequencyGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.CenterFrequencyGHzEditField.Limits = [0 Inf];
            app.CenterFrequencyGHzEditField.ValueDisplayFormat = '%11.3f';
            app.CenterFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyGHzEditFieldValueChanged, true);
            app.CenterFrequencyGHzEditField.Value = 305;
            app.MaximumBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.MaximumBandwidthGHzEditFieldLabel.Text = 'Maximum Bandwidth (GHz)';
            app.MaximumBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.MaximumBandwidthGHzEditField.Limits = [0 Inf];
            app.MaximumBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.MaximumBandwidthGHzEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumBandwidthGHzEditFieldValueChanged, true);
            app.MaximumBandwidthGHzEditField.Value = 15;
            app.TotalBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.TotalBandwidthGHzEditFieldLabel.Text = 'Total Bandwidth (GHz)';
            app.TotalBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.TotalBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.TotalBandwidthGHzEditField.Editable = 'off';
            app.WavelengthmmEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.WavelengthmmEditFieldLabel.Text = 'Wavelength (mm)';
            app.WavelengthmmEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.WavelengthmmEditField.ValueDisplayFormat = '%11.3f';
            app.WavelengthmmEditField.Editable = 'off';
            app.MaximumRangemEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.MaximumRangemEditFieldLabel.Text = 'Maximum Range (m)';
            app.MaximumRangemEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.MaximumRangemEditField.ValueDisplayFormat = '%11.3f';
            app.MaximumRangemEditField.Editable = 'off';
            app.RangeResolutionmmEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.RangeResolutionmmEditFieldLabel.Text = 'Range Resolution (mm)';
            app.RangeResolutionmmEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.RangeResolutionmmEditField.ValueDisplayFormat = '%11.3f';
            app.RangeResolutionmmEditField.Editable = 'off';
            app.UsefulBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.UsefulBandwidthGHzEditFieldLabel.Text = 'Useful Bandwidth (GHz)';
            app.UsefulBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.UsefulBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.UsefulBandwidthGHzEditField.Editable = 'off';
            app.TransmitterElementsPanel = uipanel(app.RadarSetupTab);
            app.TransmitterElementsPanel.Title = 'Transmitter Elements';
            app.TxTable = uitable(app.TransmitterElementsPanel);
            app.TxTable.ColumnName = {'x(λ)'; 'x(mm)'; 'y(λ)'; 'y(mm)'; 'On'};
            app.TxTable.RowName = {};
            app.TxTable.CellEditCallback = createCallbackFcn(app, @TxTableCellEdit, true);
            app.AddTxButton = uibutton(app.TransmitterElementsPanel, 'push');
            app.AddTxButton.ButtonPushedFcn = createCallbackFcn(app, @AddTxButtonPushed, true);
            app.AddTxButton.Text = 'Add Tx';
            app.RemoveTxButton = uibutton(app.TransmitterElementsPanel, 'push');
            app.RemoveTxButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTxButtonPushed, true);
            app.RemoveTxButton.Text = 'Remove Tx';
            app.TransmitterZmEditFieldLabel = uilabel(app.TransmitterElementsPanel);
            app.TransmitterZmEditFieldLabel.Text = 'Transmitter Z (m)';
            app.TransmitterZmEditField = uieditfield(app.TransmitterElementsPanel, 'numeric');
            app.TransmitterZmEditField.Limits = [0 Inf];
            app.TransmitterZmEditField.ValueDisplayFormat = '%11.3f';
            app.TransmitterZmEditField.ValueChangedFcn = createCallbackFcn(app, @TransmitterZmEditFieldValueChanged, true);
            app.ReceiverElementsPanel = uipanel(app.RadarSetupTab);
            app.ReceiverElementsPanel.Title = 'Receiver Elements';
            app.RxTable = uitable(app.ReceiverElementsPanel);
            app.RxTable.ColumnName = {'x(λ)'; 'x(mm)'; 'y(λ)'; 'y(mm)'; 'On'};
            app.RxTable.RowName = {};
            app.RxTable.CellEditCallback = createCallbackFcn(app, @RxTableCellEdit, true);
            app.AddRxButton = uibutton(app.ReceiverElementsPanel, 'push');
            app.AddRxButton.ButtonPushedFcn = createCallbackFcn(app, @AddRxButtonPushed, true);
            app.AddRxButton.Text = 'Add Rx';
            app.RemoveRxButton = uibutton(app.ReceiverElementsPanel, 'push');
            app.RemoveRxButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRxButtonPushed, true);
            app.RemoveRxButton.Text = 'Remove Rx';
            app.ReceiverZmEditFieldLabel = uilabel(app.ReceiverElementsPanel);
            app.ReceiverZmEditFieldLabel.Text = 'Receiver Z (m)';
            app.ReceiverZmEditField = uieditfield(app.ReceiverElementsPanel, 'numeric');
            app.ReceiverZmEditField.Limits = [0 Inf];
            app.ReceiverZmEditField.ValueDisplayFormat = '%11.3f';
            app.ReceiverZmEditField.Editable = 'off';
            app.SARSetupPanel = uipanel(app.RadarSetupTab);
            app.SARSetupPanel.Title = 'SAR Setup';
            app.MethodDropDownLabel = uilabel(app.SARSetupPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.SARMethodDropDown = uidropdown(app.SARSetupPanel);
            app.SARMethodDropDown.Items = {'-', 'Linear', 'Rectilinear', 'Circular', 'Cylindrical'};
            app.SARMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SARMethodDropDownValueChanged, true);
            app.SARMethodDropDown.Value = 'Rectilinear';
            app.XStepSizeLabel = uilabel(app.SARSetupPanel);
            app.XStepSizeLabel.Text = 'X-Step Size';
            app.XStepSizeEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.XStepSizeEditField.Limits = [0 Inf];
            app.XStepSizeEditField.ValueDisplayFormat = '%11.3f';
            app.XStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @XStepSizeEditFieldValueChanged, true);
            app.XStepSizeEditField.Value = 0.25;
            app.NumYStepsEditFieldLabel = uilabel(app.SARSetupPanel);
            app.NumYStepsEditFieldLabel.Text = 'Num Y Steps';
            app.NumYStepsEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.NumYStepsEditField.Limits = [0 Inf];
            app.NumYStepsEditField.RoundFractionalValues = 'on';
            app.NumYStepsEditField.ValueDisplayFormat = '%11.0f';
            app.NumYStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumYStepsEditFieldValueChanged, true);
            app.NumYStepsEditField.Value = 25;
            app.NumXStepsEditFieldLabel = uilabel(app.SARSetupPanel);
            app.NumXStepsEditFieldLabel.Text = 'Num X Steps';
            app.NumXStepsEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.NumXStepsEditField.Limits = [0 Inf];
            app.NumXStepsEditField.RoundFractionalValues = 'on';
            app.NumXStepsEditField.ValueDisplayFormat = '%11.0f';
            app.NumXStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumXStepsEditFieldValueChanged, true);
            app.NumXStepsEditField.Value = 200;
            app.YStepSizeLabel = uilabel(app.SARSetupPanel);
            app.YStepSizeLabel.Text = 'Y-Step Size';
            app.YStepSizeEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.YStepSizeEditField.Limits = [0 Inf];
            app.YStepSizeEditField.ValueDisplayFormat = '%11.3f';
            app.YStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @YStepSizeEditFieldValueChanged, true);
            app.YStepSizeEditField.Value = 2;
            app.NumStepsEditFieldLabel = uilabel(app.SARSetupPanel);
            app.NumStepsEditFieldLabel.Text = 'Num θ Steps';
            app.NumThetaStepsEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.NumThetaStepsEditField.Limits = [0 Inf];
            app.NumThetaStepsEditField.RoundFractionalValues = 'on';
            app.NumThetaStepsEditField.ValueDisplayFormat = '%11.0f';
            app.NumThetaStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumThetaStepsEditFieldValueChanged, true);
            app.NumThetaStepsEditField.Value = 1024;
            app.SizedegEditFieldLabel = uilabel(app.SARSetupPanel);
            app.SizedegEditFieldLabel.Text = 'θ-Size (deg)';
            app.ThetaSizedegEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.ThetaSizedegEditField.Limits = [0 Inf];
            app.ThetaSizedegEditField.ValueDisplayFormat = '%11.3f';
            app.ThetaSizedegEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaSizedegEditFieldValueChanged, true);
            app.ThetaSizedegEditField.Value = 360;
            app.XStepSwitch = uiswitch(app.SARSetupPanel, 'slider');
            app.XStepSwitch.Items = {'λ', 'mm'};
            app.XStepSwitch.ValueChangedFcn = createCallbackFcn(app, @XStepSwitchValueChanged, true);
            app.XStepSwitch.Value = 'λ';
            app.YStepSwitch = uiswitch(app.SARSetupPanel, 'slider');
            app.YStepSwitch.Items = {'λ', 'mm'};
            app.YStepSwitch.ValueChangedFcn = createCallbackFcn(app, @YStepSwitchValueChanged, true);
            app.YStepSwitch.Value = 'λ';
            app.YSizemEditFieldLabel = uilabel(app.SARSetupPanel);
            app.YSizemEditFieldLabel.Text = 'Y-Size (m)';
            app.YSizemEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.YSizemEditField.Limits = [0 Inf];
            app.YSizemEditField.ValueDisplayFormat = '%11.5f';
            app.YSizemEditField.Editable = 'off';
            app.YSizemEditField.Value = 0.25;
            app.XSizemEditFieldLabel = uilabel(app.SARSetupPanel);
            app.XSizemEditFieldLabel.Text = 'X-Size (m)';
            app.XSizemEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.XSizemEditField.Limits = [0 Inf];
            app.XSizemEditField.ValueDisplayFormat = '%11.5f';
            app.XSizemEditField.Editable = 'off';
            app.XSizemEditField.Value = 0.25;
            app.SaveLoadFMCWChripParametersPanel = uipanel(app.RadarSetupTab);
            app.SaveLoadFMCWChripParametersPanel.Title = 'Save/Load FMCW Chrip Parameters';
            app.FMCWSaveButton = uibutton(app.SaveLoadFMCWChripParametersPanel, 'push');
            app.FMCWSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FMCWSaveButtonPushed, true);
            app.FMCWSaveButton.Text = 'Save';
            app.FMCWLoadButton = uibutton(app.SaveLoadFMCWChripParametersPanel, 'push');
            app.FMCWLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FMCWLoadButtonPushed, true);
            app.FMCWLoadButton.Text = 'Load';
            app.FMCWSaveNameEditField = uieditfield(app.SaveLoadFMCWChripParametersPanel, 'text');
            app.FMCWSaveNameEditField.Value = 'fmcw_THz';
            app.FMCWLoadNameEditField = uieditfield(app.SaveLoadFMCWChripParametersPanel, 'text');
            app.FMCWLoadNameEditField.Value = 'fmcw_THz';
            app.SaveLoadMIMOArrayPanel = uipanel(app.RadarSetupTab);
            app.SaveLoadMIMOArrayPanel.Title = 'Save/Load MIMO Array';
            app.ArraySaveButton = uibutton(app.SaveLoadMIMOArrayPanel, 'push');
            app.ArraySaveButton.ButtonPushedFcn = createCallbackFcn(app, @ArraySaveButtonPushed, true);
            app.ArraySaveButton.Text = 'Save';
            app.ArrayLoadButton = uibutton(app.SaveLoadMIMOArrayPanel, 'push');
            app.ArrayLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ArrayLoadButtonPushed, true);
            app.ArrayLoadButton.Text = 'Load';
            app.ArraySaveNameEditField = uieditfield(app.SaveLoadMIMOArrayPanel, 'text');
            app.ArraySaveNameEditField.Value = 'smallMIMO';
            app.ArrayLoadNameEditField = uieditfield(app.SaveLoadMIMOArrayPanel, 'text');
            app.ArrayLoadNameEditField.Value = 'smallMIMO';
            app.OptionsPanel = uipanel(app.RadarSetupTab);
            app.OptionsPanel.Title = 'Options';
            app.ResetFiguresButton = uibutton(app.OptionsPanel, 'push');
            app.ResetFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFiguresButtonPushed, true);
            app.ResetFiguresButton.Text = 'Reset Figures';
            app.MIMOSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.MIMOSwitch.Items = {'Use MIMO Array', 'Use EPC Virtual Elements'};
            app.MIMOSwitch.ValueChangedFcn = createCallbackFcn(app, @MIMOSwitchValueChanged, true);
            app.MIMOSwitch.Value = 'Use MIMO Array';
            app.TargetSetupTab = uitab(app.TabGroup);
            app.TargetSetupTab.Title = 'Target Setup';
            app.OptionsPanel_2 = uipanel(app.TargetSetupTab);
            app.OptionsPanel_2.Title = 'Options';
            app.UseGPUCheckBox = uicheckbox(app.OptionsPanel_2);
            app.UseGPUCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGPUCheckBoxValueChanged, true);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.UseGPUCheckBox.Value = true;
            app.UseTableofTargetsCheckBox = uicheckbox(app.OptionsPanel_2);
            app.UseTableofTargetsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTableofTargetsCheckBoxValueChanged, true);
            app.UseTableofTargetsCheckBox.Text = 'Use Table of Targets';
            app.UseTableofTargetsCheckBox.Value = true;
            app.UsePNGFileCheckBox = uicheckbox(app.OptionsPanel_2);
            app.UsePNGFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsePNGFileCheckBoxValueChanged, true);
            app.UsePNGFileCheckBox.Text = 'Use PNG File';
            app.UseSTLFileCheckBox = uicheckbox(app.OptionsPanel_2);
            app.UseSTLFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSTLFileCheckBoxValueChanged, true);
            app.UseSTLFileCheckBox.Text = 'Use STL File';
            app.PathLossCheckBox = uicheckbox(app.OptionsPanel_2);
            app.PathLossCheckBox.Text = 'Path Loss';
            app.TableofTargetsPanel = uipanel(app.TargetSetupTab);
            app.TableofTargetsPanel.Title = 'Table of Targets';
            app.TargetTable = uitable(app.TableofTargetsPanel);
            app.TargetTable.ColumnName = {'x (m)'; 'y (m)'; 'z (m)'; 'σ'};
            app.TargetTable.RowName = {};
            app.TargetTable.CellEditCallback = createCallbackFcn(app, @TargetTableCellEdit, true);
            app.AddTargetButton = uibutton(app.TableofTargetsPanel, 'push');
            app.AddTargetButton.ButtonPushedFcn = createCallbackFcn(app, @AddTargetButtonPushed, true);
            app.AddTargetButton.Text = 'Add Target';
            app.RemoveTargetButton = uibutton(app.TableofTargetsPanel, 'push');
            app.RemoveTargetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTargetButtonPushed, true);
            app.RemoveTargetButton.Text = 'Remove Target';
            app.GenerateRandomPointsPanel = uipanel(app.TargetSetupTab);
            app.GenerateRandomPointsPanel.Title = 'Generate Random Points';
            app.GenerateRandomPointsButton = uibutton(app.GenerateRandomPointsPanel, 'push');
            app.GenerateRandomPointsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomPointsButtonPushed, true);
            app.GenerateRandomPointsButton.Text = 'Generate Random Points';
            app.NumTargetsEditField_3Label = uilabel(app.GenerateRandomPointsPanel);
            app.NumTargetsEditField_3Label.Text = 'Num Targets';
            app.NumTargetsEditField_3 = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.NumTargetsEditField_3.Limits = [1 Inf];
            app.NumTargetsEditField_3.RoundFractionalValues = 'on';
            app.NumTargetsEditField_3.ValueDisplayFormat = '%11.3g';
            app.NumTargetsEditField_3.Value = 16;
            app.XMinmEditFieldLabel = uilabel(app.GenerateRandomPointsPanel);
            app.XMinmEditFieldLabel.Text = 'X Min (m)';
            app.XMinmEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.XMinmEditField.ValueDisplayFormat = '%11.3f';
            app.XMinmEditField.Value = -0.05;
            app.XMaxmLabel = uilabel(app.GenerateRandomPointsPanel);
            app.XMaxmLabel.Text = 'X Max (m)';
            app.XMaxmEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.XMaxmEditField.ValueDisplayFormat = '%11.3f';
            app.XMaxmEditField.Value = 0.05;
            app.YMinmEditFieldLabel = uilabel(app.GenerateRandomPointsPanel);
            app.YMinmEditFieldLabel.Text = 'Y Min (m)';
            app.YMinmEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.YMinmEditField.ValueDisplayFormat = '%11.3f';
            app.YMinmEditField.Value = -0.05;
            app.YMaxmEditFieldLabel = uilabel(app.GenerateRandomPointsPanel);
            app.YMaxmEditFieldLabel.Text = 'Y Max (m)';
            app.YMaxmEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.YMaxmEditField.ValueDisplayFormat = '%11.3f';
            app.YMaxmEditField.Value = 0.05;
            app.ZMinmEditFieldLabel = uilabel(app.GenerateRandomPointsPanel);
            app.ZMinmEditFieldLabel.Text = 'Z Min (m)';
            app.ZMinmEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.ZMinmEditField.ValueDisplayFormat = '%11.3f';
            app.ZMinmEditField.Value = 0.05;
            app.ZMaxmEditFieldLabel = uilabel(app.GenerateRandomPointsPanel);
            app.ZMaxmEditFieldLabel.Text = 'Z Max (m)';
            app.ZMaxmEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.ZMaxmEditField.ValueDisplayFormat = '%11.3f';
            app.ZMaxmEditField.Value = 0.2;
            app.ReflectivityMinEditFieldLabel = uilabel(app.GenerateRandomPointsPanel);
            app.ReflectivityMinEditFieldLabel.Text = 'Reflectivity Min';
            app.ReflectivityMinEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.ReflectivityMinEditField.ValueDisplayFormat = '%11.3f';
            app.ReflectivityMinEditField.Value = 0.5;
            app.ReflectivityMaxEditFieldLabel = uilabel(app.GenerateRandomPointsPanel);
            app.ReflectivityMaxEditFieldLabel.Text = 'Reflectivity Max';
            app.ReflectivityMaxEditField = uieditfield(app.GenerateRandomPointsPanel, 'numeric');
            app.ReflectivityMaxEditField.ValueDisplayFormat = '%11.3f';
            app.ReflectivityMaxEditField.Value = 1;
            app.ImportPNGImagePanel = uipanel(app.TargetSetupTab);
            app.ImportPNGImagePanel.Title = 'Import PNG Image';
            app.XPixelSizemEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.XPixelSizemEditFieldLabel.Text = 'X-Pixel Size (m) ';
            app.XPixelSizemEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.XPixelSizemEditField.Limits = [0 Inf];
            app.XPixelSizemEditField.ValueDisplayFormat = '%11.5f';
            app.XPixelSizemEditField.ValueChangedFcn = createCallbackFcn(app, @XPixelSizemEditFieldValueChanged, true);
            app.XPixelSizemEditField.Value = 0.0002;
            app.YPixelSizemEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.YPixelSizemEditFieldLabel.Text = 'Y-Pixel Size (m) ';
            app.YPixelSizemEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.YPixelSizemEditField.Limits = [0 Inf];
            app.YPixelSizemEditField.ValueDisplayFormat = '%11.5f';
            app.YPixelSizemEditField.ValueChangedFcn = createCallbackFcn(app, @YPixelSizemEditFieldValueChanged, true);
            app.YPixelSizemEditField.Value = 0.0002;
            app.XOffsetmEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.XOffsetmEditFieldLabel.Text = 'X Offset (m)';
            app.XOffsetmEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.XOffsetmEditField.ValueDisplayFormat = '%11.5f';
            app.XOffsetmEditField.ValueChangedFcn = createCallbackFcn(app, @XOffsetmEditFieldValueChanged, true);
            app.XOffsetmEditField.Value = -0.0025;
            app.YOffsetmEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.YOffsetmEditFieldLabel.Text = 'Y Offset (m)';
            app.YOffsetmEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.YOffsetmEditField.ValueDisplayFormat = '%11.5f';
            app.YOffsetmEditField.ValueChangedFcn = createCallbackFcn(app, @YOffsetmEditFieldValueChanged, true);
            app.YOffsetmEditField.Value = 0.005;
            app.DownsampleFactorEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.DownsampleFactorEditFieldLabel.Text = 'Downsample Factor';
            app.DownsampleFactorEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.DownsampleFactorEditField.Limits = [1 Inf];
            app.DownsampleFactorEditField.ValueDisplayFormat = '%11.3g';
            app.DownsampleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DownsampleFactorEditFieldValueChanged, true);
            app.DownsampleFactorEditField.Value = 4;
            app.ZOffsetmEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.ZOffsetmEditFieldLabel.Text = 'Z Offset (m)';
            app.ZOffsetmEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.ZOffsetmEditField.Limits = [0 Inf];
            app.ZOffsetmEditField.ValueDisplayFormat = '%11.5f';
            app.ZOffsetmEditField.ValueChangedFcn = createCallbackFcn(app, @ZOffsetmEditFieldValueChanged, true);
            app.ZOffsetmEditField.Value = 0.15;
            app.ReflectivityEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.ReflectivityEditFieldLabel.Text = 'Reflectivity';
            app.ReflectivityEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.ReflectivityEditField.Limits = [0 Inf];
            app.ReflectivityEditField.ValueDisplayFormat = '%11.3f';
            app.ReflectivityEditField.ValueChangedFcn = createCallbackFcn(app, @ReflectivityEditFieldValueChanged, true);
            app.ReflectivityEditField.Value = 1;
            app.PNGFileNameEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.PNGFileNameEditFieldLabel.Text = 'PNG File Name';
            app.PNGFileNameEditField = uieditfield(app.ImportPNGImagePanel, 'text');
            app.PNGFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @PNGFileNameEditFieldValueChanged, true);
            app.PNGFileNameEditField.Value = 'circle.png';
            app.NumTargetsEditFieldLabel = uilabel(app.ImportPNGImagePanel);
            app.NumTargetsEditFieldLabel.Text = 'Num Targets';
            app.NumTargetsEditField = uieditfield(app.ImportPNGImagePanel, 'numeric');
            app.NumTargetsEditField.Limits = [0 Inf];
            app.NumTargetsEditField.ValueDisplayFormat = '%i';
            app.NumTargetsEditField.Editable = 'off';
            app.NumTargetsEditField.Value = 1;
            app.ImportSTLFilePanel = uipanel(app.TargetSetupTab);
            app.ImportSTLFilePanel.Title = 'Import STL File';
            app.XOffsetmEditField_2Label = uilabel(app.ImportSTLFilePanel);
            app.XOffsetmEditField_2Label.Text = 'X Offset (m)';
            app.XOffsetmEditField_2 = uieditfield(app.ImportSTLFilePanel, 'numeric');
            app.XOffsetmEditField_2.ValueDisplayFormat = '%11.3f';
            app.XOffsetmEditField_2.ValueChangedFcn = createCallbackFcn(app, @XOffsetmEditField_2ValueChanged, true);
            app.YOffsetmEditField_2Label = uilabel(app.ImportSTLFilePanel);
            app.YOffsetmEditField_2Label.Text = 'Y Offset (m)';
            app.YOffsetmEditField_2 = uieditfield(app.ImportSTLFilePanel, 'numeric');
            app.YOffsetmEditField_2.ValueDisplayFormat = '%11.3f';
            app.YOffsetmEditField_2.ValueChangedFcn = createCallbackFcn(app, @YOffsetmEditField_2ValueChanged, true);
            app.DownsampleFactorEditField_2Label = uilabel(app.ImportSTLFilePanel);
            app.DownsampleFactorEditField_2Label.Text = 'Downsample Factor';
            app.DownsampleFactorEditField_2 = uieditfield(app.ImportSTLFilePanel, 'numeric');
            app.DownsampleFactorEditField_2.Limits = [1 Inf];
            app.DownsampleFactorEditField_2.ValueDisplayFormat = '%11.3g';
            app.DownsampleFactorEditField_2.ValueChangedFcn = createCallbackFcn(app, @DownsampleFactorEditField_2ValueChanged, true);
            app.DownsampleFactorEditField_2.Value = 40;
            app.ZOffsetmEditField_2Label = uilabel(app.ImportSTLFilePanel);
            app.ZOffsetmEditField_2Label.Text = 'Z Offset (m)';
            app.ZOffsetmEditField_2 = uieditfield(app.ImportSTLFilePanel, 'numeric');
            app.ZOffsetmEditField_2.ValueDisplayFormat = '%11.3f';
            app.ZOffsetmEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZOffsetmEditField_2ValueChanged, true);
            app.ZOffsetmEditField_2.Value = 0.1;
            app.ReflectivityEditField_2Label = uilabel(app.ImportSTLFilePanel);
            app.ReflectivityEditField_2Label.Text = 'Reflectivity';
            app.ReflectivityEditField_2 = uieditfield(app.ImportSTLFilePanel, 'numeric');
            app.ReflectivityEditField_2.Limits = [0 Inf];
            app.ReflectivityEditField_2.ValueDisplayFormat = '%11.3f';
            app.ReflectivityEditField_2.ValueChangedFcn = createCallbackFcn(app, @ReflectivityEditField_2ValueChanged, true);
            app.ReflectivityEditField_2.Value = 1;
            app.STLFileNameEditFieldLabel = uilabel(app.ImportSTLFilePanel);
            app.STLFileNameEditFieldLabel.Text = 'STL File Name';
            app.STLFileNameEditField = uieditfield(app.ImportSTLFilePanel, 'text');
            app.STLFileNameEditField.Value = 'ar15.stl';
            app.NumTargetsEditField_2Label = uilabel(app.ImportSTLFilePanel);
            app.NumTargetsEditField_2Label.Text = 'Num Targets';
            app.NumTargetsEditField_2 = uieditfield(app.ImportSTLFilePanel, 'numeric');
            app.NumTargetsEditField_2.Limits = [0 Inf];
            app.NumTargetsEditField_2.ValueDisplayFormat = '%i';
            app.NumTargetsEditField_2.Editable = 'off';
            app.NumTargetsEditField_2.Value = 1;
            app.STLLoadButton = uibutton(app.ImportSTLFilePanel, 'push');
            app.STLLoadButton.ButtonPushedFcn = createCallbackFcn(app, @STLLoadButtonPushed, true);
            app.STLLoadButton.Text = 'Load STL';
            app.ZCropmEditFieldLabel = uilabel(app.ImportSTLFilePanel);
            app.ZCropmEditFieldLabel.Text = 'Z Crop (m)';
            app.ZCropmEditField = uieditfield(app.ImportSTLFilePanel, 'numeric');
            app.ZCropmEditField.ValueDisplayFormat = '%11.3f';
            app.ZCropmEditField.ValueChangedFcn = createCallbackFcn(app, @ZCropmEditFieldValueChanged, true);
            app.ZCropmEditField.Value = 1;
            app.LoadSTLLamp = uilamp(app.ImportSTLFilePanel);
            app.SaveLoadTargetPanel = uipanel(app.TargetSetupTab);
            app.SaveLoadTargetPanel.Title = 'Save/Load Target';
            app.TargetSaveButton = uibutton(app.SaveLoadTargetPanel, 'push');
            app.TargetSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TargetSaveButtonPushed, true);
            app.TargetSaveButton.Text = 'Save';
            app.TargetLoadButton = uibutton(app.SaveLoadTargetPanel, 'push');
            app.TargetLoadButton.ButtonPushedFcn = createCallbackFcn(app, @TargetLoadButtonPushed, true);
            app.TargetLoadButton.Text = 'Load';
            app.TargetSaveNameEditField = uieditfield(app.SaveLoadTargetPanel, 'text');
            app.TargetSaveNameEditField.Value = 'target_v1';
            app.TargetLoadNameEditField = uieditfield(app.SaveLoadTargetPanel, 'text');
            app.TargetLoadNameEditField.Value = 'target_v2';
            app.SimulateBeatSignalPanel = uipanel(app.TargetSetupTab);
            app.SimulateBeatSignalPanel.Title = 'Simulate Beat Signal';
            app.ComputeBeatSignalButton = uibutton(app.SimulateBeatSignalPanel, 'push');
            app.ComputeBeatSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeBeatSignalButtonPushed, true);
            app.ComputeBeatSignalButton.Text = 'Compute Beat Signal';
            app.TotalNumTargetsEditFieldLabel = uilabel(app.SimulateBeatSignalPanel);
            app.TotalNumTargetsEditFieldLabel.Text = 'Total Num Targets';
            app.TotalNumTargetsEditField = uieditfield(app.SimulateBeatSignalPanel, 'numeric');
            app.TotalNumTargetsEditField.Limits = [0 Inf];
            app.TotalNumTargetsEditField.ValueDisplayFormat = '%i';
            app.TotalNumTargetsEditField.Editable = 'off';
            app.TotalNumTargetsEditField.Value = 1;
            app.ReconstructImageTab = uitab(app.TabGroup);
            app.ReconstructImageTab.Title = 'Reconstruct Image';
            app.ImageReconstructionTabGroup = uitabgroup(app.ReconstructImageTab);
            app.Uniform2DSAR3DRMATab = uitab(app.ImageReconstructionTabGroup);
            app.Uniform2DSAR3DRMATab.Title = 'Uniform 2-D SAR 3-D RMA';
            app.XMinmEditField_2Label_2 = uilabel(app.Uniform2DSAR3DRMATab);
            app.XMinmEditField_2Label_2.Text = 'X Min (m)';
            app.XMinmEditField_im_2 = uieditfield(app.Uniform2DSAR3DRMATab, 'numeric');
            app.XMinmEditField_im_2.ValueDisplayFormat = '%11.4f';
            app.XMinmEditField_im_2.Value = -0.2;
            app.XMaxmEditField_2Label_2 = uilabel(app.Uniform2DSAR3DRMATab);
            app.XMaxmEditField_2Label_2.Text = 'X Max (m)';
            app.XMaxmEditField_im_2 = uieditfield(app.Uniform2DSAR3DRMATab, 'numeric');
            app.XMaxmEditField_im_2.ValueDisplayFormat = '%11.4f';
            app.XMaxmEditField_im_2.Value = 0.2;
            app.YMinmEditField_2Label_2 = uilabel(app.Uniform2DSAR3DRMATab);
            app.YMinmEditField_2Label_2.Text = 'Y Min (m)';
            app.YMinmEditField_im_2 = uieditfield(app.Uniform2DSAR3DRMATab, 'numeric');
            app.YMinmEditField_im_2.ValueDisplayFormat = '%11.4f';
            app.YMinmEditField_im_2.Value = -0.2;
            app.YMaxmEditField_2Label_2 = uilabel(app.Uniform2DSAR3DRMATab);
            app.YMaxmEditField_2Label_2.Text = 'Y Max (m)';
            app.YMaxmEditField_im_2 = uieditfield(app.Uniform2DSAR3DRMATab, 'numeric');
            app.YMaxmEditField_im_2.ValueDisplayFormat = '%11.4f';
            app.YMaxmEditField_im_2.Value = 0.2;
            app.ZMinmEditField_2Label_2 = uilabel(app.Uniform2DSAR3DRMATab);
            app.ZMinmEditField_2Label_2.Text = 'Z Min (m)';
            app.ZMinmEditField_im_2 = uieditfield(app.Uniform2DSAR3DRMATab, 'numeric');
            app.ZMinmEditField_im_2.ValueDisplayFormat = '%11.4f';
            app.ZMinmEditField_im_2.Value = 0.8;
            app.ZMaxmEditField_2Label_2 = uilabel(app.Uniform2DSAR3DRMATab);
            app.ZMaxmEditField_2Label_2.Text = 'Z Max (m)';
            app.ZMaxmEditField_im_2 = uieditfield(app.Uniform2DSAR3DRMATab, 'numeric');
            app.ZMaxmEditField_im_2.ValueDisplayFormat = '%11.4f';
            app.ZMaxmEditField_im_2.Value = 1.2;
            app.Uniform1DSAR2DRMATab = uitab(app.ImageReconstructionTabGroup);
            app.Uniform1DSAR2DRMATab.Title = 'Uniform 1-D SAR 2-D RMA';
            app.YMinmEditField_2Label_5 = uilabel(app.Uniform1DSAR2DRMATab);
            app.YMinmEditField_2Label_5.Text = 'Y Min (m)';
            app.YMinmEditField_im_5 = uieditfield(app.Uniform1DSAR2DRMATab, 'numeric');
            app.YMinmEditField_im_5.ValueDisplayFormat = '%11.4f';
            app.YMinmEditField_im_5.Value = -0.2;
            app.YMaxmEditField_2Label_5 = uilabel(app.Uniform1DSAR2DRMATab);
            app.YMaxmEditField_2Label_5.Text = 'Y Max (m)';
            app.YMaxmEditField_im_5 = uieditfield(app.Uniform1DSAR2DRMATab, 'numeric');
            app.YMaxmEditField_im_5.ValueDisplayFormat = '%11.4f';
            app.YMaxmEditField_im_5.Value = 0.2;
            app.ZMinmEditField_2Label_3 = uilabel(app.Uniform1DSAR2DRMATab);
            app.ZMinmEditField_2Label_3.Text = 'Z Min (m)';
            app.ZMinmEditField_im_3 = uieditfield(app.Uniform1DSAR2DRMATab, 'numeric');
            app.ZMinmEditField_im_3.ValueDisplayFormat = '%11.4f';
            app.ZMinmEditField_im_3.Value = 0.8;
            app.ZMaxmEditField_2Label_3 = uilabel(app.Uniform1DSAR2DRMATab);
            app.ZMaxmEditField_2Label_3.Text = 'Z Max (m)';
            app.ZMaxmEditField_im_3 = uieditfield(app.Uniform1DSAR2DRMATab, 'numeric');
            app.ZMaxmEditField_im_3.ValueDisplayFormat = '%11.4f';
            app.ZMaxmEditField_im_3.Value = 1.2;
            app.Uniform2DSAR2DFFTTab = uitab(app.ImageReconstructionTabGroup);
            app.Uniform2DSAR2DFFTTab.Title = 'Uniform 2-D SAR 2-D FFT';
            app.ZSlicemLabel_2 = uilabel(app.Uniform2DSAR2DFFTTab);
            app.ZSlicemLabel_2.Text = 'Z Slice (m)';
            app.ZSliceEditField_2 = uieditfield(app.Uniform2DSAR2DFFTTab, 'numeric');
            app.ZSliceEditField_2.ValueDisplayFormat = '%11.4f';
            app.ZSliceEditField_2.Value = 0.1;
            app.XMinmEditField_2Label_3 = uilabel(app.Uniform2DSAR2DFFTTab);
            app.XMinmEditField_2Label_3.Text = 'X Min (m)';
            app.XMinmEditField_im_3 = uieditfield(app.Uniform2DSAR2DFFTTab, 'numeric');
            app.XMinmEditField_im_3.ValueDisplayFormat = '%11.4f';
            app.XMinmEditField_im_3.Value = -0.2;
            app.XMaxmEditField_2Label_3 = uilabel(app.Uniform2DSAR2DFFTTab);
            app.XMaxmEditField_2Label_3.Text = 'X Max (m)';
            app.XMaxmEditField_im_3 = uieditfield(app.Uniform2DSAR2DFFTTab, 'numeric');
            app.XMaxmEditField_im_3.ValueDisplayFormat = '%11.4f';
            app.XMaxmEditField_im_3.Value = 0.2;
            app.YMinmEditField_2Label_3 = uilabel(app.Uniform2DSAR2DFFTTab);
            app.YMinmEditField_2Label_3.Text = 'Y Min (m)';
            app.YMinmEditField_im_3 = uieditfield(app.Uniform2DSAR2DFFTTab, 'numeric');
            app.YMinmEditField_im_3.ValueDisplayFormat = '%11.4f';
            app.YMinmEditField_im_3.Value = -0.2;
            app.YMaxmEditField_2Label_3 = uilabel(app.Uniform2DSAR2DFFTTab);
            app.YMaxmEditField_2Label_3.Text = 'Y Max (m)';
            app.YMaxmEditField_im_3 = uieditfield(app.Uniform2DSAR2DFFTTab, 'numeric');
            app.YMaxmEditField_im_3.ValueDisplayFormat = '%11.4f';
            app.YMaxmEditField_im_3.Value = 0.2;
            app.Uniform1DSAR1DFFTTab = uitab(app.ImageReconstructionTabGroup);
            app.Uniform1DSAR1DFFTTab.Title = 'Uniform 1-D SAR 1-D FFT';
            app.ZSlicemLabel_3 = uilabel(app.Uniform1DSAR1DFFTTab);
            app.ZSlicemLabel_3.Text = 'Z Slice (m)';
            app.ZSliceEditField_3 = uieditfield(app.Uniform1DSAR1DFFTTab, 'numeric');
            app.ZSliceEditField_3.ValueDisplayFormat = '%11.4f';
            app.ZSliceEditField_3.Value = 0.1;
            app.YMinmEditField_2Label_4 = uilabel(app.Uniform1DSAR1DFFTTab);
            app.YMinmEditField_2Label_4.Text = 'Y Min (m)';
            app.YMinmEditField_im_4 = uieditfield(app.Uniform1DSAR1DFFTTab, 'numeric');
            app.YMinmEditField_im_4.ValueDisplayFormat = '%11.4f';
            app.YMinmEditField_im_4.Value = -0.2;
            app.YMaxmEditField_2Label_4 = uilabel(app.Uniform1DSAR1DFFTTab);
            app.YMaxmEditField_2Label_4.Text = 'Y Max (m)';
            app.YMaxmEditField_im_4 = uieditfield(app.Uniform1DSAR1DFFTTab, 'numeric');
            app.YMaxmEditField_im_4.ValueDisplayFormat = '%11.4f';
            app.YMaxmEditField_im_4.Value = 0.2;
            app.DSAR2DBPATab = uitab(app.ImageReconstructionTabGroup);
            app.DSAR2DBPATab.Title = '2-D SAR 2-D BPA';
            app.ZSlicemLabel = uilabel(app.DSAR2DBPATab);
            app.ZSlicemLabel.Text = 'Z Slice (m)';
            app.ZSliceEditField = uieditfield(app.DSAR2DBPATab, 'numeric');
            app.ZSliceEditField.ValueDisplayFormat = '%11.4f';
            app.ZSliceEditField.Value = 0.1;
            app.Uniform2DCSAR3DPFATab = uitab(app.ImageReconstructionTabGroup);
            app.Uniform2DCSAR3DPFATab.Title = 'Uniform 2-D CSAR 3-D PFA';
            app.XMinmEditField_2Label_4 = uilabel(app.Uniform2DCSAR3DPFATab);
            app.XMinmEditField_2Label_4.Text = 'X Min (m)';
            app.XMinmEditField_im_4 = uieditfield(app.Uniform2DCSAR3DPFATab, 'numeric');
            app.XMinmEditField_im_4.ValueDisplayFormat = '%11.4f';
            app.XMinmEditField_im_4.Value = -0.2;
            app.XMaxmEditField_2Label_4 = uilabel(app.Uniform2DCSAR3DPFATab);
            app.XMaxmEditField_2Label_4.Text = 'X Max (m)';
            app.XMaxmEditField_im_4 = uieditfield(app.Uniform2DCSAR3DPFATab, 'numeric');
            app.XMaxmEditField_im_4.ValueDisplayFormat = '%11.4f';
            app.XMaxmEditField_im_4.Value = 0.2;
            app.YMinmEditField_2Label_6 = uilabel(app.Uniform2DCSAR3DPFATab);
            app.YMinmEditField_2Label_6.Text = 'Y Min (m)';
            app.YMinmEditField_im_6 = uieditfield(app.Uniform2DCSAR3DPFATab, 'numeric');
            app.YMinmEditField_im_6.ValueDisplayFormat = '%11.4f';
            app.YMinmEditField_im_6.Value = -0.2;
            app.YMaxmEditField_2Label_6 = uilabel(app.Uniform2DCSAR3DPFATab);
            app.YMaxmEditField_2Label_6.Text = 'Y Max (m)';
            app.YMaxmEditField_im_6 = uieditfield(app.Uniform2DCSAR3DPFATab, 'numeric');
            app.YMaxmEditField_im_6.ValueDisplayFormat = '%11.4f';
            app.YMaxmEditField_im_6.Value = 0.2;
            app.ZMinmEditField_2Label_4 = uilabel(app.Uniform2DCSAR3DPFATab);
            app.ZMinmEditField_2Label_4.Text = 'Z Min (m)';
            app.ZMinmEditField_im_4 = uieditfield(app.Uniform2DCSAR3DPFATab, 'numeric');
            app.ZMinmEditField_im_4.ValueDisplayFormat = '%11.4f';
            app.ZMinmEditField_im_4.Value = 0.8;
            app.ZMaxmEditField_2Label_4 = uilabel(app.Uniform2DCSAR3DPFATab);
            app.ZMaxmEditField_2Label_4.Text = 'Z Max (m)';
            app.ZMaxmEditField_im_4 = uieditfield(app.Uniform2DCSAR3DPFATab, 'numeric');
            app.ZMaxmEditField_im_4.ValueDisplayFormat = '%11.4f';
            app.ZMaxmEditField_im_4.Value = 1.2;
            app.UpsampleFactorLabel = uilabel(app.Uniform2DCSAR3DPFATab);
            app.UpsampleFactorLabel.Text = 'θ Upsample Factor';
            app.ThetaUpsampleFactorEditField = uieditfield(app.Uniform2DCSAR3DPFATab, 'numeric');
            app.ThetaUpsampleFactorEditField.Limits = [0 Inf];
            app.ThetaUpsampleFactorEditField.RoundFractionalValues = 'on';
            app.ThetaUpsampleFactorEditField.Value = 2;
            app.Uniform1DCSAR2DPFATab = uitab(app.ImageReconstructionTabGroup);
            app.Uniform1DCSAR2DPFATab.Title = 'Uniform 1-D CSAR 2-D PFA';
            app.XMinmEditField_2Label_5 = uilabel(app.Uniform1DCSAR2DPFATab);
            app.XMinmEditField_2Label_5.Text = 'X Min (m)';
            app.XMinmEditField_im_5 = uieditfield(app.Uniform1DCSAR2DPFATab, 'numeric');
            app.XMinmEditField_im_5.ValueDisplayFormat = '%11.4f';
            app.XMinmEditField_im_5.Value = -0.2;
            app.XMaxmEditField_2Label_5 = uilabel(app.Uniform1DCSAR2DPFATab);
            app.XMaxmEditField_2Label_5.Text = 'X Max (m)';
            app.XMaxmEditField_im_5 = uieditfield(app.Uniform1DCSAR2DPFATab, 'numeric');
            app.XMaxmEditField_im_5.ValueDisplayFormat = '%11.4f';
            app.XMaxmEditField_im_5.Value = 0.2;
            app.ZMinmEditField_2Label_5 = uilabel(app.Uniform1DCSAR2DPFATab);
            app.ZMinmEditField_2Label_5.Text = 'Z Min (m)';
            app.ZMinmEditField_im_5 = uieditfield(app.Uniform1DCSAR2DPFATab, 'numeric');
            app.ZMinmEditField_im_5.ValueDisplayFormat = '%11.4f';
            app.ZMinmEditField_im_5.Value = 0.8;
            app.ZMaxmEditField_2Label_5 = uilabel(app.Uniform1DCSAR2DPFATab);
            app.ZMaxmEditField_2Label_5.Text = 'Z Max (m)';
            app.ZMaxmEditField_im_5 = uieditfield(app.Uniform1DCSAR2DPFATab, 'numeric');
            app.ZMaxmEditField_im_5.ValueDisplayFormat = '%11.4f';
            app.ZMaxmEditField_im_5.Value = 1.2;
            app.UpsampleFactorLabel_2 = uilabel(app.Uniform1DCSAR2DPFATab);
            app.UpsampleFactorLabel_2.Text = 'θ Upsample Factor';
            app.ThetaUpsampleFactorEditField_2 = uieditfield(app.Uniform1DCSAR2DPFATab, 'numeric');
            app.ThetaUpsampleFactorEditField_2.Limits = [0 Inf];
            app.ThetaUpsampleFactorEditField_2.RoundFractionalValues = 'on';
            app.ThetaUpsampleFactorEditField_2.Value = 2;
            app.ImageReconstructionOptionsPanel = uipanel(app.ReconstructImageTab);
            app.ImageReconstructionOptionsPanel.Title = 'Reconstruction Algorithm';
            app.NumberofXFFTPointsEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.NumberofXFFTPointsEditField_2Label.Text = 'Number of X FFT Points';
            app.NumberofXFFTPointsEditField = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.NumberofXFFTPointsEditField.Limits = [0 Inf];
            app.NumberofXFFTPointsEditField.RoundFractionalValues = 'on';
            app.NumberofXFFTPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofXFFTPointsEditFieldValueChanged, true);
            app.NumberofXFFTPointsEditField.Value = 512;
            app.NumberofYFFTPointsEditField_2Label_2 = uilabel(app.ImageReconstructionOptionsPanel);
            app.NumberofYFFTPointsEditField_2Label_2.Text = 'Number of Y FFT Points';
            app.NumberofYFFTPointsEditField = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.NumberofYFFTPointsEditField.Limits = [0 Inf];
            app.NumberofYFFTPointsEditField.RoundFractionalValues = 'on';
            app.NumberofYFFTPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofYFFTPointsEditFieldValueChanged, true);
            app.NumberofYFFTPointsEditField.Value = 512;
            app.NumberofZFFTPointsEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.NumberofZFFTPointsEditField_2Label.Text = 'Number of Z FFT Points';
            app.NumberofZFFTPointsEditField = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.NumberofZFFTPointsEditField.Limits = [0 Inf];
            app.NumberofZFFTPointsEditField.RoundFractionalValues = 'on';
            app.NumberofZFFTPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofZFFTPointsEditFieldValueChanged, true);
            app.NumberofZFFTPointsEditField.Value = 512;
            app.ReconstructionAlgorithmDropDown = uidropdown(app.ImageReconstructionOptionsPanel);
            app.ReconstructionAlgorithmDropDown.Items = {'-', 'Uniform 2-D SAR 3-D RMA', 'Uniform 1-D SAR 2-D RMA', 'Uniform 2-D SAR 2-D FFT', 'Uniform 1-D SAR 1-D FFT', '2-D SAR 3-D BPA', '2-D SAR 2-D BPA', 'Uniform 2-D CSAR 3-D PFA', 'Uniform 1-D CSAR 2-D PFA', '2-D CSAR 3-D BPA', '1-D CSAR 2-D BPA', 'Custom'};
            app.ReconstructionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ReconstructionAlgorithmDropDownValueChanged, true);
            app.ReconstructionAlgorithmDropDown.Value = '-';
            app.XMinmEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.XMinmEditField_2Label.Text = 'X Min (m)';
            app.XMinmEditField_im = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.XMinmEditField_im.ValueDisplayFormat = '%11.4f';
            app.XMinmEditField_im.ValueChangedFcn = createCallbackFcn(app, @XMinmEditField_imValueChanged, true);
            app.XMinmEditField_im.Value = -0.05;
            app.XMaxmEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.XMaxmEditField_2Label.Text = 'X Max (m)';
            app.XMaxmEditField_im = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.XMaxmEditField_im.ValueDisplayFormat = '%11.4f';
            app.XMaxmEditField_im.ValueChangedFcn = createCallbackFcn(app, @XMaxmEditField_imValueChanged, true);
            app.XMaxmEditField_im.Value = 0.05;
            app.YMinmEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.YMinmEditField_2Label.Text = 'Y Min (m)';
            app.YMinmEditField_im = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.YMinmEditField_im.ValueDisplayFormat = '%11.4f';
            app.YMinmEditField_im.ValueChangedFcn = createCallbackFcn(app, @YMinmEditField_imValueChanged, true);
            app.YMinmEditField_im.Value = -0.05;
            app.YMaxmEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.YMaxmEditField_2Label.Text = 'Y Max (m)';
            app.YMaxmEditField_im = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.YMaxmEditField_im.ValueDisplayFormat = '%11.4f';
            app.YMaxmEditField_im.ValueChangedFcn = createCallbackFcn(app, @YMaxmEditField_imValueChanged, true);
            app.YMaxmEditField_im.Value = 0.05;
            app.ZMinmEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.ZMinmEditField_2Label.Text = 'Z Min (m)';
            app.ZMinmEditField_im = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.ZMinmEditField_im.ValueDisplayFormat = '%11.4f';
            app.ZMinmEditField_im.ValueChangedFcn = createCallbackFcn(app, @ZMinmEditField_imValueChanged, true);
            app.ZMaxmEditField_2Label = uilabel(app.ImageReconstructionOptionsPanel);
            app.ZMaxmEditField_2Label.Text = 'Z Max (m)';
            app.ZMaxmEditField_im = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.ZMaxmEditField_im.ValueDisplayFormat = '%11.4f';
            app.ZMaxmEditField_im.ValueChangedFcn = createCallbackFcn(app, @ZMaxmEditField_imValueChanged, true);
            app.ZMaxmEditField_im.Value = 0.2;
            app.NumXVoxelsEditFieldLabel = uilabel(app.ImageReconstructionOptionsPanel);
            app.NumXVoxelsEditFieldLabel.Text = 'Num X Voxels';
            app.NumXVoxelsEditField = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.NumXVoxelsEditField.Limits = [0 Inf];
            app.NumXVoxelsEditField.RoundFractionalValues = 'on';
            app.NumXVoxelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumXVoxelsEditFieldValueChanged, true);
            app.NumXVoxelsEditField.Value = 128;
            app.NumYVoxelsEditFieldLabel = uilabel(app.ImageReconstructionOptionsPanel);
            app.NumYVoxelsEditFieldLabel.Text = 'Num Y Voxels';
            app.NumYVoxelsEditField = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.NumYVoxelsEditField.Limits = [0 Inf];
            app.NumYVoxelsEditField.RoundFractionalValues = 'on';
            app.NumYVoxelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumYVoxelsEditFieldValueChanged, true);
            app.NumYVoxelsEditField.Value = 128;
            app.NumZVoxelsEditFieldLabel = uilabel(app.ImageReconstructionOptionsPanel);
            app.NumZVoxelsEditFieldLabel.Text = 'Num Z Voxels';
            app.NumZVoxelsEditField = uieditfield(app.ImageReconstructionOptionsPanel, 'numeric');
            app.NumZVoxelsEditField.Limits = [0 Inf];
            app.NumZVoxelsEditField.RoundFractionalValues = 'on';
            app.NumZVoxelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumZVoxelsEditFieldValueChanged, true);
            app.NumZVoxelsEditField.Value = 128;
            app.ReconstructTargetButton = uibutton(app.ImageReconstructionOptionsPanel, 'push');
            app.ReconstructTargetButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructTargetButtonPushed, true);
            app.ReconstructTargetButton.Text = 'Reconstruct Target';
            app.UseGPUCheckBox_2 = uicheckbox(app.ImageReconstructionOptionsPanel);
            app.UseGPUCheckBox_2.Text = 'Use GPU';
            app.DisplayOptionsPanel = uipanel(app.ReconstructImageTab);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.OpeninVolumeViewerButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.OpeninVolumeViewerButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninVolumeViewerButtonPushed, true);
            app.OpeninVolumeViewerButton.Text = 'Open in Volume Viewer';
            app.DisplayImageButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.DisplayImageButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayImageButtonPushed, true);
            app.DisplayImageButton.Text = 'Display Image';
            app.MindBEditFieldLabel = uilabel(app.DisplayOptionsPanel);
            app.MindBEditFieldLabel.Text = 'Min dB';
            app.MindBEditField = uieditfield(app.DisplayOptionsPanel, 'numeric');
            app.MindBEditField.Limits = [-Inf 0];
            app.MindBEditField.Value = -25;
            app.FontSizeEditFieldLabel = uilabel(app.DisplayOptionsPanel);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.DisplayOptionsPanel, 'numeric');
            app.FontSizeEditField.Limits = [0 Inf];
            app.FontSizeEditField.RoundFractionalValues = 'on';
            app.FontSizeEditField.Value = 12;
            app.MutlistatictoMonostaticConversionPanel = uipanel(app.ReconstructImageTab);

            app.MutlistatictoMonostaticConversionPanel.Title = 'Mutlistatic to Monostatic Conversion';
            app.isMult2MonoCheckBox = uicheckbox(app.MutlistatictoMonostaticConversionPanel);
            app.isMult2MonoCheckBox.Text = 'Use multistatic-to-monostatic EPC approximation on MIMO array';
            app.isMult2MonoCheckBox.Value = true;
            app.ZReferencemLabel = uilabel(app.MutlistatictoMonostaticConversionPanel);
            app.ZReferencemLabel.Text = 'Z Reference (m)';
            app.ZReferencemEditField = uieditfield(app.MutlistatictoMonostaticConversionPanel, 'numeric');
            app.ZReferencemEditField.Limits = [0 Inf];
            app.ZReferencemEditField.ValueDisplayFormat = '%11.4f';
            app.ZReferencemEditField.Value = 0.1;
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel = uilabel(app.AboutTab);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel.Text = {'Copyright (C) 2021'; ''; 'This program is free software: you can redistribute it and/or modify'; 'it under the terms of the GNU General Public License as published by'; 'the Free Software Foundation, either version 3 of the License, or'; '(at your option) any later version.'; ''; 'This program is distributed in the hope that it will be useful,'; 'but WITHOUT ANY WARRANTY; without even the implied warranty of'; 'MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the'; 'GNU General Public License for more details.'; ''};
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_2 = uilabel(app.AboutTab);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_2.Text = {'Created by WISLAB - UTD'; ''};
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_3 = uilabel(app.AboutTab);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_3.Text = {'The University of Texas at Dallas'; 'Wireless Information Systems Laboratory'; ''};
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_4 = uilabel(app.AboutTab);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_4.Text = {'Author: Josiah W. Smith'; 'Advisor: Murat Torlak'; ''; 'Contact: josiah.smith@utdallas.edu'; 'Website: utdallas.edu/~wislab'};
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_5 = uilabel(app.AboutTab);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_5.Text = 'Electrical and Computer Engineering (ECE) Department';
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_6 = uilabel(app.AboutTab);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel_6.Text = 'ece.utdallas.edu';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'UTD_logo_4cA.gif';
            app.AntennaArrayUptoDateLampLabel = uilabel(app.UIFigure);
            app.AntennaArrayUptoDateLampLabel.Text = 'Antenna Array Up to Date';
            app.AntennaArrayUptoDateLamp = uilamp(app.UIFigure);
            app.SARScenarioUptoDateLampLabel = uilabel(app.UIFigure);
            app.SARScenarioUptoDateLampLabel.Text = 'SAR Scenario Up to Date';
            app.SARScenarioUptoDateLamp = uilamp(app.UIFigure);
            app.TargetUptoDateLampLabel = uilabel(app.UIFigure);
            app.TargetUptoDateLampLabel.Text = 'Target Up to Date';
            app.TargetUptoDateLamp = uilamp(app.UIFigure);
            app.BeatSignalComputedLampLabel = uilabel(app.UIFigure);
            app.BeatSignalComputedLampLabel.Text = 'Beat Signal Computed';
            app.BeatSignalComputedLamp = uilamp(app.UIFigure);
            app.ImageReconstructionCompleteLampLabel = uilabel(app.UIFigure);
            app.ImageReconstructionCompleteLampLabel.Text = 'Image Reconstruction Complete';
            app.ImageReconstructionCompleteLamp = uilamp(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'UTDallas_wdmk_4c.gif';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'UTD_logo_4cA.gif';
            app.WISLABWebsiteButton = uibutton(app.UIFigure, 'push');
            app.WISLABWebsiteButton.ButtonPushedFcn = createCallbackFcn(app, @WISLABWebsiteButtonPushed, true);
            app.WISLABWebsiteButton.Text = 'WISLAB Website';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fmcw_mimo_sar_simulator_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end